classdef FINISHWeeklyToDoListManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        Image10                matlab.ui.control.Image
        ToDoListManagerLabel   matlab.ui.control.Label
        ATHENALabel            matlab.ui.control.Label
        DueDateDropDown        matlab.ui.control.DropDown
        DueDateDropDownLabel   matlab.ui.control.Label
        AddTaskButton          matlab.ui.control.Button
        CategoryDropDown       matlab.ui.control.DropDown
        CategoryDropDownLabel  matlab.ui.control.Label
        PriorityDropDown       matlab.ui.control.DropDown
        PriorityDropDownLabel  matlab.ui.control.Label
        TaskEditField          matlab.ui.control.EditField
        TaskEditFieldLabel     matlab.ui.control.Label
        Image2                 matlab.ui.control.Image
        Image2_3               matlab.ui.control.Image
        Image_2                matlab.ui.control.Image
        Image2_2               matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        RightPanel             matlab.ui.container.Panel
        ThisWeeksListLabel     matlab.ui.control.Label
        SortbyDropDown         matlab.ui.control.DropDown
        SortbyDropDownLabel    matlab.ui.control.Label
        DeleteTaskButton       matlab.ui.control.Button
        MarkasDoneButton       matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        Image2_5               matlab.ui.control.Image
        Image2_4               matlab.ui.control.Image
        Image2_6               matlab.ui.control.Image
        Image_3                matlab.ui.control.Image
        Image_4                matlab.ui.control.Image
        Image2_7               matlab.ui.control.Image
        Image2_9               matlab.ui.control.Image
        Image_5                matlab.ui.control.Image
        Image2_8               matlab.ui.control.Image
        Image_6                matlab.ui.control.Image
        ContextMenu            matlab.ui.container.ContextMenu
        Menu                   matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddTaskButton
        function AddTaskButtonPushed(app, event)
    % Retrieve input data from UI components
taskName = char(app.TaskEditField.Value);

dueDate = char(app.DueDateDropDown.Value);


    priority = char(app.PriorityDropDown.Value); 
    category = char(app.CategoryDropDown.Value);

    % Add the data to the table (replace with your own data storage mechanism)
    newRow = {taskName, dueDate, priority, category};
    app.UITable.Data = [app.UITable.Data; newRow];
        end

        % Callback function
        function EditButtonPushed(app, event)
          % Retrieve the selected row in the table
     selectedRowIndex = app.UITable.UserData.SelectedRow;

    if ~isempty(selectedRowIndex)
        % Get the data from the selected row
        selectedRowData = app.UITable.Data(selectedRowIndex, :);

        % Extract individual components of the selected row
        taskName = selectedRowData{1};
        dueDate = selectedRowData{2}; 
        priority = selectedRowData{3};
        category = selectedRowData{4};

        % Open a dialog or input fields for the user to modify the values
        % Implement logic to update taskName, dueDate, priority, category
        
        % Update the selected row with the modified values
        app.UITable.Data(selectedRowIndex, :) = {taskName, dueDate, priority, category};
    else
        % Provide a message indicating no task is selected for editing
        disp('No task is selected for editing.');
    end  
        end

        % Button pushed function: DeleteTaskButton
        function DeleteTaskButtonPushed(app, event)
          % Get the selected row indices directly from the table
    selectedRows = app.UITable.Selection;

    if ~isempty(selectedRows)
        % Extract the row indices from the selection data
        rowIndices = selectedRows(:, 1);

        % Remove the selected rows from the table data
        currentData = app.UITable.Data;
        currentData(rowIndices, :) = [];

        % Update the table data after deletion
        app.UITable.Data = currentData;
    else
        % Provide a message indicating no rows are selected for deletion
        disp('No task is selected for deletion.');
    end
        end

        % Value changed function: SortbyDropDown
        function SortbyDropDownValueChanged(app, event)
   tableData = app.UITable.Data;
    selectedCriteria = app.SortbyDropDown.Value;

    % Define the order for sorting based on criteria
    switch selectedCriteria
        case 'Deadline'
            % Define a mapping for days of the week
            dayOrder = containers.Map({'Monday', 'Tuesday', 'Wednesday', 'Thursday', 'Friday', 'Saturday','Sunday'}, {1, 2, 3, 4, 5, 6, 7});
            % Convert day labels to numerical values for sorting (second column)
            dayValues = cellfun(@(x) dayOrder(x), tableData(:, 2));
            % Sort by the numerical day values
            [~, idx] = sort(dayValues);
            sortedData = tableData(idx, :);
        case 'Priority'
            % Custom sorting based on priority levels
            priorityOrder = containers.Map({'High', 'Medium', 'Low'}, {1, 2, 3});
            % Convert priority labels to numerical values for sorting
            priorityValues = cellfun(@(x) priorityOrder(x), tableData(:, 3));
            % Sort by the numerical priority values (third column)
            [~, idx] = sort(priorityValues);
            sortedData = tableData(idx, :);
        case 'Category'
            % Define a mapping for categories
            categoryOrder = containers.Map({'Academics', 'Org Works', 'Chores', 'Personal Goals'}, {1, 2, 3, 4});
            % Convert category labels to numerical values for sorting
            categoryValues = cellfun(@(x) categoryOrder(x), tableData(:, 4));
            % Sort by the numerical category values (fourth column)
            [~, idx] = sort(categoryValues);
            sortedData = tableData(idx, :);
        otherwise
            % Handle unexpected criteria or maintain the existing order
            sortedData = tableData;
    end

    % Update the table's data
    app.UITable.Data = sortedData;

            
        end

        % Button pushed function: MarkasDoneButton
        function MarkasDoneButtonPushed(app, event)
            selectedRows = app.UITable.Selection;

if ~isempty(selectedRows)
    % Get the number of selected rows
    numSelected = size(selectedRows, 1);

    % Display a message indicating tasks completed
    completedTasksMsg = sprintf('%d task(s) completed.', numSelected);
    disp(completedTasksMsg);

    % Remove selected rows from the table
    rowIndices = selectedRows(:, 1);
    currentData = app.UITable.Data;
    currentData(rowIndices, :) = [];
    app.UITable.Data = currentData;
else
    % Provide a message indicating no rows are selected
    disp('No rows are selected.');
end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {485, 485};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {188, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster.png');
            app.Image2_2 = uiimage(app.LeftPanel);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster (1).png');
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster.png');
            app.Image2_3 = uiimage(app.LeftPanel);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster (1).png');
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster (1).png');
            app.TaskEditFieldLabel = uilabel(app.LeftPanel);
            app.TaskEditFieldLabel.Text = 'Task';
            app.TaskEditField = uieditfield(app.LeftPanel, 'text');
            app.PriorityDropDownLabel = uilabel(app.LeftPanel);
            app.PriorityDropDownLabel.Text = 'Priority ';
            app.PriorityDropDown = uidropdown(app.LeftPanel);
            app.PriorityDropDown.Items = {'', 'High', 'Medium', 'Low'};
            app.PriorityDropDown.Value = 'High';
            app.CategoryDropDownLabel = uilabel(app.LeftPanel);
            app.CategoryDropDownLabel.Text = 'Category';
            app.CategoryDropDown = uidropdown(app.LeftPanel);
            app.CategoryDropDown.Items = {'', 'Academics', 'Org Works', 'Chores', 'Personal Goals'};
            app.CategoryDropDown.Value = 'Academics';
            app.AddTaskButton = uibutton(app.LeftPanel, 'push');
            app.AddTaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddTaskButtonPushed, true);
            app.AddTaskButton.Text = 'Add Task';
            app.DueDateDropDownLabel = uilabel(app.LeftPanel);
            app.DueDateDropDownLabel.Text = 'Due Date';
            app.DueDateDropDown = uidropdown(app.LeftPanel);
            app.DueDateDropDown.Items = {'', 'Monday', 'Tuesday', 'Wednesday', 'Thursday', 'Friday', 'Saturday', 'Sunday'};
            app.DueDateDropDown.Value = 'Monday';
            app.ATHENALabel = uilabel(app.LeftPanel);
            app.ATHENALabel.Text = 'ATHENA';
            app.ToDoListManagerLabel = uilabel(app.LeftPanel);
            app.ToDoListManagerLabel.Text = 'To-Do List Manager';
            app.Image10 = uiimage(app.LeftPanel);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'Chiaki studio.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_6 = uiimage(app.RightPanel);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster.png');
            app.Image2_8 = uiimage(app.RightPanel);
            app.Image2_8.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster (1).png');
            app.Image_5 = uiimage(app.RightPanel);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster.png');
            app.Image2_9 = uiimage(app.RightPanel);
            app.Image2_9.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster (1).png');
            app.Image2_7 = uiimage(app.RightPanel);
            app.Image2_7.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster (1).png');
            app.Image_4 = uiimage(app.RightPanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster.png');
            app.Image_3 = uiimage(app.RightPanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster.png');
            app.Image2_6 = uiimage(app.RightPanel);
            app.Image2_6.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster (1).png');
            app.Image2_4 = uiimage(app.RightPanel);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster (1).png');
            app.Image2_5 = uiimage(app.RightPanel);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'PICTURES', 'White Illustrative Motivational Education Quotes Classroom Poster (1).png');
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Task'; 'Due Date'; 'Priority'; 'Category'};
            app.UITable.RowName = {};
            app.MarkasDoneButton = uibutton(app.RightPanel, 'push');
            app.MarkasDoneButton.ButtonPushedFcn = createCallbackFcn(app, @MarkasDoneButtonPushed, true);
            app.MarkasDoneButton.Text = 'Mark as Done';
            app.DeleteTaskButton = uibutton(app.RightPanel, 'push');
            app.DeleteTaskButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTaskButtonPushed, true);
            app.DeleteTaskButton.Text = 'Delete Task';
            app.SortbyDropDownLabel = uilabel(app.RightPanel);
            app.SortbyDropDownLabel.Text = 'Sort by:';
            app.SortbyDropDown = uidropdown(app.RightPanel);
            app.SortbyDropDown.Items = {'', 'Deadline', 'Priority', 'Category'};
            app.SortbyDropDown.ValueChangedFcn = createCallbackFcn(app, @SortbyDropDownValueChanged, true);
            app.SortbyDropDown.Value = 'Deadline';
            app.ThisWeeksListLabel = uilabel(app.RightPanel);
            app.ThisWeeksListLabel.Text = 'This Week''s List';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINISHWeeklyToDoListManager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end